classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        a11EditFieldLabel          matlab.ui.control.Label
        a11EditField               matlab.ui.control.NumericEditField
        a12EditFieldLabel          matlab.ui.control.Label
        a12EditField               matlab.ui.control.NumericEditField
        a14EditField_3Label        matlab.ui.control.Label
        a14EditField_3             matlab.ui.control.NumericEditField
        TypeofcurveEditFieldLabel  matlab.ui.control.Label
        TypeofcurveEditField       matlab.ui.control.EditField
        CalculateButton            matlab.ui.control.Button
        a22EditField_3Label        matlab.ui.control.Label
        a22EditField_3             matlab.ui.control.NumericEditField
        a23EditFieldLabel          matlab.ui.control.Label
        a23EditField               matlab.ui.control.NumericEditField
        a24EditFieldLabel          matlab.ui.control.Label
        a24EditField               matlab.ui.control.NumericEditField
        a33EditField_3Label        matlab.ui.control.Label
        a33EditField_3             matlab.ui.control.NumericEditField
        a13EditFieldLabel          matlab.ui.control.Label
        a13EditField               matlab.ui.control.NumericEditField
        a34EditFieldLabel          matlab.ui.control.Label
        a34EditField               matlab.ui.control.NumericEditField
        a44EditFieldLabel          matlab.ui.control.Label
        a44EditField               matlab.ui.control.NumericEditField
        InputcoefficientsofflatsurfaceAxByCzD0Label  matlab.ui.control.Label
        Inputcoefficientsofa11x2a22y2a33z22a12DLabel  matlab.ui.control.Label
        AEditFieldLabel            matlab.ui.control.Label
        AEditField                 matlab.ui.control.NumericEditField
        BEditFieldLabel            matlab.ui.control.Label
        BEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        DEditField                 matlab.ui.control.NumericEditField
        CEditFieldLabel            matlab.ui.control.Label
        CEditField                 matlab.ui.control.NumericEditField
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cla(app.UIAxes)
            
            syms x y z
            
            a = app.AEditField.Value
            b = app.BEditField.Value
            c = app.CEditField.Value
            d = app.DEditField.Value
            
            a11 = app.a11EditField.Value
            a22 = app.a22EditField_3.Value
            a33 = app.a33EditField_3.Value
            
            a12 = app.a12EditField.Value
            a23 = app.a23EditField.Value
            a13 = app.a13EditField.Value
            
            a14 = app.a14EditField_3.Value
            a24 = app.a24EditField.Value
            a34 = app.a34EditField.Value
            
            a44 = app.a44EditField.Value
            
            if (c ~= 0)
                flt_s = @(x, y) -a/c .* x  - b/c .* y  + d/c
            elseif (b ~= 0)
                flt_s = @(x, z) -a/b .*x - c/b .* z + d/b
            elseif (a ~= 0)
                flt_s = @(y, z) -b/a .* y - c/a .* z + d/a
            else
                flt_s = @(x, z) d
            end
            
            %if (c ~= 0)
            %    ss = @(x, y) sqrt(-a_s/c_s * x.^2  - b_s/c_s * y.^2  + d_s/c_s)
            %elseif (b ~= 0)
            %    ss = @(x, z) sqrt(-a_s/b_s *x.^2 - c_s/b_s * y.^2 + d_s/b_s)
            %elseif (a ~= 0)
            %    ss = @(y, z) sqrt(-b_s/a_s * y.^2 - c_s/a_s * y.^2 + d_s/a_s)
            %else
            %    ss = @(x, z) d
            %end
            
            
            f_s = a11*x^2 + a22*y^2 + a33*z^2 + 2*a12*x*y + 2*a23*y*z + 2*a13*x*z + 2*a14*x + 2*a24*y + 2*a34*z + a44
            f_f = a*x + b*y + c*z + d
            
            flt1_s = @(x, y, z) a.*x + b.*y + c.*z + d;
            ss1 = @(x,y,z) a11*x.^2 + a22*y.^2 + a33*z.^2 + 2*a12*x.*y + 2*a23*y.*z + 2*a13*x.*z + 2*a14.*x + 2.*a24*y + 2.*a34*z + a44;
            
            mss = @(x,y) -ss(x,y);
            
            xmi = app.UIAxes.XLim(1);
            xmx = app.UIAxes.XLim(2);
            
            [x3, y3, z3] = meshgrid(linspace(xmi,xmx));
            
            f1 = ss1(x3,y3,z3);
            f2 = flt1_s(x3,y3,z3);
            
            f3 = f1 - f2;
            
            
            if (c ~= 0)
               [x2, y2] = meshgrid(linspace(app.UIAxes.XLim(1),app.UIAxes.XLim(2)));
               z2 = -a/c .* x2  - b/c .* y2  - d/c;
               f3s = interp3(x3, y3, z3, f3, x2, y2, z2);
               C = contours(x2, y2, f3s, [0 0]);
            elseif(b ~= 0)
               [x2, z2] = meshgrid(linspace(app.UIAxes.XLim(1),app.UIAxes.XLim(2)));
               y2 = -a/b .* x2  - c/b .* z2  - d/b;
               f3s = interp3(x3, y3, z3, f3, x2, y2, z2);
               C = contours(x2, z2, f3s, [0 0]);
            elseif(a ~= 0)
               [y2, z2] = meshgrid(linspace(app.UIAxes.XLim(1),app.UIAxes.XLim(2)));
               x2 = -b/a .* y2  - c/a .* z2  - d/a;
               f3s = interp3(x3, y3, z3, f3, x2, y2, z2);
               C = contours(y2, z2, f3s, [0 0]);
            else
                app.TypeofcurveEditField.Value = "None";
                return;
            end


            xL = C(1, 2:end);
            yL = C(2, 2:end);
            zL = interp2(x2, y2, z2, xL, yL);
            
            label = "None";

            if(a ~= 0)
                X = solve(f_f==0, x)
                F = collect(subs(f_s, x, X));
                x1 = y; x2 = z;
            elseif(b ~= 0)
                Z = solve(f_f==0, z)
                F = collect(subs(f_s, z, Z));
                x1 = x; x2 = y;
            elseif(c ~= 0)
                Z = solve(f_f==0, z)
                F = collect(subs(f_s, z, Z));
                x1 = x; x2 = y;
            else
                app.TypeofcurveEditField.Value = "None";
                return;
            end
            % F = b11x^2 + b22y^2 + 2b12xy + 2b13x + 2b23y + b33 //
            % Мы найдём коэффициенты продиффиренцировав функцию по разным
            % переменным
            F = simplify(F)
            b11 = diff(F, x1, 2) / 2
            b12 = diff(diff(F,x1),x2) / 2
            b22 = diff(F, x2, 2) / 2
            
            F2 = simplify(F - b11*x1^2 - 2*b12*x1*x2 - b22*x2^2)
            
            b13 = diff(F2, x1, 1) / 2
            b23 = diff(F2, x2, 1) / 2
            
            b33 = simplify(F2 - 2*b13*x1 - 2*b23*x2)
            
            inv2 = det([b11 b12; b12 b22])
            inv3 = det([b11 b12 b13; b12 b22 b23; b13 b23 b33])
            
            if(inv2 ~=0)
                if(inv2 > 0)
                    label = "ellipse";
                elseif(inv2 < 0)
                    label = "hyperbola";
                end
            else
                if(inv3 == 0)
                    label = "Two straight lines";
                else
                    label = "Parabola";
                end
            end
            hold(app.UIAxes,'on');
            %fsurf(app.UIAxes,flt_s, [xmi xmx xmi xmx]);
            %fsurf(app.UIAxes,ss, [xmi xmx xmi xmx]);
            %fsurf(app.UIAxes,mss, [xmi xmx xmi xmx]);
            fimplicit3(app.UIAxes, f_s);
            fimplicit3(app.UIAxes, f_f);
            line(app.UIAxes,xL,yL,zL,'Color','k','LineWidth',3);
            
            hold(app.UIAxes, 'off')


            app.TypeofcurveEditField.Value = label;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {254, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.a11EditFieldLabel = uilabel(app.LeftPanel);
            app.a11EditFieldLabel.Text = 'a11';
            app.a11EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a11EditField.Value = 1;
            app.a12EditFieldLabel = uilabel(app.LeftPanel);
            app.a12EditFieldLabel.Text = 'a12';
            app.a12EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a14EditField_3Label = uilabel(app.LeftPanel);
            app.a14EditField_3Label.Text = 'a14';
            app.a14EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.TypeofcurveEditFieldLabel = uilabel(app.LeftPanel);
            app.TypeofcurveEditFieldLabel.Text = 'Type of curve';
            app.TypeofcurveEditField = uieditfield(app.LeftPanel, 'text');
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = {'Calculate'; ''};
            app.a22EditField_3Label = uilabel(app.LeftPanel);
            app.a22EditField_3Label.Text = 'a22';
            app.a22EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.a22EditField_3.Value = 1;
            app.a23EditFieldLabel = uilabel(app.LeftPanel);
            app.a23EditFieldLabel.Text = 'a23';
            app.a23EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a24EditFieldLabel = uilabel(app.LeftPanel);
            app.a24EditFieldLabel.Text = 'a24';
            app.a24EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a33EditField_3Label = uilabel(app.LeftPanel);
            app.a33EditField_3Label.Text = 'a33';
            app.a33EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.a33EditField_3.Value = 1;
            app.a13EditFieldLabel = uilabel(app.LeftPanel);
            app.a13EditFieldLabel.Text = 'a13';
            app.a13EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a34EditFieldLabel = uilabel(app.LeftPanel);
            app.a34EditFieldLabel.Text = 'a34';
            app.a34EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a44EditFieldLabel = uilabel(app.LeftPanel);
            app.a44EditFieldLabel.Text = 'a44';
            app.a44EditField = uieditfield(app.LeftPanel, 'numeric');
            app.a44EditField.Value = -1;
            app.InputcoefficientsofflatsurfaceAxByCzD0Label = uilabel(app.LeftPanel);
            app.InputcoefficientsofflatsurfaceAxByCzD0Label.Text = {'Input coefficients of flat surface '; 'Ax + By + Cz + D = 0'};
            app.Inputcoefficientsofa11x2a22y2a33z22a12DLabel = uilabel(app.LeftPanel);
            app.Inputcoefficientsofa11x2a22y2a33z22a12DLabel.Text = {'Input coefficients of surface:'; ''; 'a11x^2 + a22y^2 + a33z^2 + 2a12xy +'; '+ 2a23yz + 2a13xz + 2a14x + 2a24y +'; '+ 2a34z + a44 = 0'};
            app.AEditFieldLabel = uilabel(app.LeftPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.LeftPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BEditField.Value = 1;
            app.DEditFieldLabel = uilabel(app.LeftPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DEditField.Value = -1;
            app.CEditFieldLabel = uilabel(app.LeftPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plots')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [-10 10];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end